classdef MergePDF_V01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MergePDF_V01UIFigure        matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout4                 matlab.ui.container.GridLayout
        PathEditField               matlab.ui.control.EditField
        PathEditFieldLabel          matlab.ui.control.Label
        MessageEditField            matlab.ui.control.EditField
        MessageEditFieldLabel       matlab.ui.control.Label
        NameEditField               matlab.ui.control.EditField
        NameEditFieldLabel          matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        GridLayout3                 matlab.ui.container.GridLayout
        Step2MergefilesLabel        matlab.ui.control.Label
        MergePDFfilesButton         matlab.ui.control.Button
        GridLayout2                 matlab.ui.container.GridLayout
        Step1SelectinputfilesLabel  matlab.ui.control.Label
        InputPDFfilesButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InputPDFfilesButton
        function InputPDFfilesButtonPushed(app, event)
            [fileNames,pathname_input]=uigetfile('*.pdf','Select pdf file','MultiSelect','on');
            fileNames=cellfun(@(x) strcat(pathname_input,x),fileNames,'UniformOutput',false);
            app.UITable.Data=fileNames';
        end

        % Button pushed function: MergePDFfilesButton
        function MergePDFfilesButtonPushed(app, event)
            pathout=uigetdir('\','Select pdf Output path');
            outputName=strcat(app.NameEditField.Value,'.pdf');
            outputFile=strcat(pathout,'\',outputName);
            app.PathEditField.Value=outputFile;
            mergePdfs(app.UITable.Data', outputFile)
            app.MessageEditField.Value="Successful merge pdf!";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MergePDF_V01UIFigure = uifigure('Visible', 'off');
            app.MergePDF_V01UIFigure.Name = 'MergePDF_V01';
            app.GridLayout = uigridlayout(app.MergePDF_V01UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.InputPDFfilesButton = uibutton(app.GridLayout2, 'push');
            app.InputPDFfilesButton.ButtonPushedFcn = createCallbackFcn(app, @InputPDFfilesButtonPushed, true);
            app.InputPDFfilesButton.Text = 'Input PDF files';
            app.Step1SelectinputfilesLabel = uilabel(app.GridLayout2);
            app.Step1SelectinputfilesLabel.Text = 'Step1：Select input files';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.MergePDFfilesButton = uibutton(app.GridLayout3, 'push');
            app.MergePDFfilesButton.ButtonPushedFcn = createCallbackFcn(app, @MergePDFfilesButtonPushed, true);
            app.MergePDFfilesButton.Text = 'Merge PDF files';
            app.Step2MergefilesLabel = uilabel(app.GridLayout3);
            app.Step2MergefilesLabel.Text = 'Step2：Merge files';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Input File'};
            app.UITable.RowName = {};
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.NameEditFieldLabel = uilabel(app.GridLayout4);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridLayout4, 'text');
            app.NameEditField.Value = 'MergedPDF';
            app.MessageEditFieldLabel = uilabel(app.GridLayout4);
            app.MessageEditFieldLabel.Text = 'Message';
            app.MessageEditField = uieditfield(app.GridLayout4, 'text');
            app.MessageEditField.Editable = 'off';
            app.PathEditFieldLabel = uilabel(app.GridLayout4);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.GridLayout4, 'text');
            % Show the figure after all components are created
            app.MergePDF_V01UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MergePDF_V01

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MergePDF_V01UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MergePDF_V01UIFigure)
        end
    end
end